classdef day3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel_8           matlab.ui.container.Panel
        UIAxes4_2         matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
        Panel_6           matlab.ui.container.Panel
        Image2            matlab.ui.control.Image
        Image3            matlab.ui.control.Image
        UIAxes3           matlab.ui.control.UIAxes
        Panel_5           matlab.ui.container.Panel
        Button_9          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Label_13          matlab.ui.control.Label
        Label_12          matlab.ui.control.Label
        Slider_3          matlab.ui.control.Slider
        Label_11          matlab.ui.control.Label
        DropDown_4        matlab.ui.control.DropDown
        Label_8           matlab.ui.control.Label
        Slider_2          matlab.ui.control.Slider
        Image_2           matlab.ui.control.Image
        Image             matlab.ui.control.Image
        Label_7           matlab.ui.control.Label
        DropDown_3        matlab.ui.control.DropDown
        Label_6           matlab.ui.control.Label
        Button_7          matlab.ui.control.Button
        Slider            matlab.ui.control.Slider
        Label_5           matlab.ui.control.Label
        Panel_7           matlab.ui.container.Panel
        kHzSlider_5       matlab.ui.control.Slider
        kHzSlider_5Label  matlab.ui.control.Label
        kHzSlider_4       matlab.ui.control.Slider
        kHzSlider_4Label  matlab.ui.control.Label
        kHzSlider_3       matlab.ui.control.Slider
        kHzSlider_3Label  matlab.ui.control.Label
        kHzSlider_2       matlab.ui.control.Slider
        kHzSlider_2Label  matlab.ui.control.Label
        kHzSlider         matlab.ui.control.Slider
        kHzSliderLabel    matlab.ui.control.Label
        HzSlider_4        matlab.ui.control.Slider
        HzSlider_4Label   matlab.ui.control.Label
        HzSlider_3        matlab.ui.control.Slider
        HzSlider_3Label   matlab.ui.control.Label
        HzSlider_2        matlab.ui.control.Slider
        HzSlider_2Label   matlab.ui.control.Label
        HzSlider          matlab.ui.control.Slider
        HzSliderLabel     matlab.ui.control.Label
        Panel_2           matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        Panel             matlab.ui.container.Panel
        DropDown_5        matlab.ui.control.DropDown
        Label_9           matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        Label_4           matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        DropDown_2        matlab.ui.control.DropDown
        Label_2           matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        signal % Description
        audio
        audio_changed
        sound_inst = 2;
        player = []
        fs = 44100
        sound_fs = 100
        sound_value = 1
        music
        music_out
        music_state = struct( ...
            'speed', [], ...
            'reverb_type', [], ...
            'reverb_volume', [], ...
            'volume', [], ...
            'equalizer', [] ...
        );
        music_name
        music_volume = 100
        music_speed = 1
        music_reverb = struct('b', 1, 'a', 1)
        music_reverb_volume = 1
        music_reverb_choose = 0
        is_enable = [0 0 0 0]
        eq_gains = [0 0 0 0 0 0 0 0 0]            % 9段增益值 [-12~12dB]
        center_freqs = [62, 125, 250, 500, 1000, 2000, 4000, 8000, 16000]
    end
    
    methods (Access = private)

        %% 音符函数（包络+谐波）
        function note = n(~,T, f, instru)
            dt = 1/44100;
            t = 0:dt:T;
            sound = sin(2*pi*f*t);
            if instru == 1
                % 指数衰减包络
                if abs(T - 0.25) < 1e-6  % 8分音符
                    env = (t.^4) .* exp(-60 * sqrt(t));
                elseif abs(T - 0.5) < 1e-6  % 4分音符
                    env = (t.^4) .* exp(-35 * sqrt(t));
                elseif  abs(T - 1) < 1e-6  %2分
                    env = (t.^4) .* exp(-25 * sqrt(t));
                else %比2分更长
                    env = (t.^4) .* exp(-15 * sqrt(t));
                end
                 env = env / max(env);
            elseif instru == 2
                % 长笛包络
                attack_time = 0.06 * T;  % 攻击时间：5% of T
                decay_time = 0.048 * T;   % 衰减时间：5% of T
                sustain_level = 0.42*T;     % 维持水平：90% of peak
                release_time = 0.134 * T;  % 释放时间：10% of T
                % 计算维持时间
                sustain_time = T - attack_time - decay_time - release_time;
                % 初始化包络
                env = zeros(size(t));
                % 计算各阶段包络
                for i = 1:length(t)
                    if t(i) < attack_time
                        % 攻击阶段：线性从0到1
                        env(i) = t(i) / attack_time;
                    elseif t(i) < attack_time + decay_time
                        % 衰减阶段：线性从1到sustain_level
                        env(i) = 1 - (1 - sustain_level) * (t(i) - attack_time) / decay_time;
                    elseif t(i) < attack_time + decay_time + sustain_time
                        % 维持阶段：保持sustain_level
                        env(i) = sustain_level;
                    else
                        % 释放阶段：线性从sustain_level到0
                        env(i) = sustain_level * (1 - (t(i) - (attack_time + decay_time + sustain_time)) / release_time);
                    end
                end
                % 归一化，使最大值等于1
                env = env / max(env);

                harmonic_ratios = [
                        1.0000, 0.3536, 0.1925, 0.1250, 0.0894, 0.0680, 0.0538, 0.0442, 0.0370, 0.0316, ...
                        0.0275, 0.0242, 0.0216, 0.0194, 0.0176, 0.0160, 0.0147, 0.0135, 0.0125, 0.0116, ...
                        0.0108, 0.0101, 0.0095, 0.0089, 0.0084, 0.0079, 0.0075, 0.0071, 0.0067, 0.0064, ...
                        0.0061, 0.0058, 0.0055, 0.0053, 0.0050, 0.0048, 0.0046, 0.0044, 0.0042, 0.0041, ...
                        0.0039, 0.0038, 0.0036, 0.0035, 0.0034, 0.0033, 0.0032, 0.0031, 0.0030, 0.0029, ...
                        0.0028, 0.0027, 0.0026, 0.0025, 0.0024, 0.0024, 0.0023, 0.0022, 0.0022, 0.0021, ...
                        0.0021, 0.0020, 0.0020, 0.0019, 0.0019, 0.0018, 0.0018, 0.0017, 0.0017, 0.0017
                        ];
                % 谐波合成
                for n = 2: length(harmonic_ratios)
                    amplitude = harmonic_ratios(n); % 直接使用分析结果的比例
                    sound = sound + amplitude * sin(2*pi*n*f*t); % 合成正弦波
                end
            elseif instru == 3
                %吉他包络
                attack_time = 0.01 * T;  % 起音时间
                decay_time = 0.03 * T;   % 衰减时间
                decay_level = 0.35;       % 衰减后的振幅水平
                release_time = T - attack_time - decay_time; % 释放时间
                % Initialize envelope
                env = zeros(size(t));
                % Compute envelope segments
                for i = 1:length(t)
                    if t(i) < attack_time
                        % 起音：从0线性上升到1
                        env(i) = t(i) / attack_time;
                    elseif t(i) < attack_time + decay_time
                        % 衰减：从1线性下降到decay_level
                        env(i) = 1 - (1 - decay_level) * (t(i) - attack_time) / decay_time;
                    else
                        % 释放：从decay_level指数衰减到0
                        env(i) = decay_level * exp(-(t(i) - (attack_time + decay_time)) / (release_time / 8));
                    end
                end
                % Normalize envelope to max amplitude of 1
                env = env / max(env);

                harmonic_ratios = [
                1.0000, 0.8963, 0.0227, 0.4458, 0.1971, ...
                0.0501, 0.0399, 0.1193, 0.1075, 0.0364, ...
                0.0415, 0.0132, 0.0072, 0.0014, 0.0165, ...
                0.0050, 0.0109, 0.0050, 0.0013, 0.0036, ...
                0.0010, 0.0032, 0.0019, 0.0056, 0.0025, ...
                0.0124, 0.0141, 0.0054, 0.0036, 0.0012, ...
                0.0011, 0.0017, 0.0023, 0.0005, 0.0037, ...
                0.0020, 0.0011, 0.0008, 0.0008, 0.0010, ...
                0.0020, 0.0073, 0.0029, 0.0013, 0.0008, ...
                0.0009, 0.0025, 0.0006, 0.0012, 0.0015, ...
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                0.0006, 0.0006, 0.0006, 0.0006, 0.0006
                ];
                % 谐波合成
                for n = 2: length(harmonic_ratios)
                    amplitude = harmonic_ratios(n); % 直接使用分析结果的比例
                    sound = sound + amplitude * sin(2*pi*n*f*t); % 合成正弦波
                end
            else
                env = 1;
            end
            sound = sound ./max(sound);
            note = sound .* env;
        end

        function melody = sound_make(app, instru)
                %% F大调频率
                % Notes: do3(F3), re3(G3), mi3(A3), fa3(Bb3), sol3(C4), la3(D4), si3(E4)
                do3=174.61; re3=195.99; mi3=220.00; fa3=233.08; sol3=261.63; la3=293.66; si3=329.63;
                % Notes: do4(F4), re4(G4), mi4(A4), fa4(Bb4), sol4(C5), la4(D5), si4(E5)
                do4=349.23; re4=391.95; mi4=440.00; fa4=466.16; sol4=523.25; la4=587.33; si4=659.25;
                % Notes: do5(F5), re5(G5), mi5(A5), fa5(Bb5), sol5(C6), la5(D6), si5(E6), rst
                do5=698.46; re5=783.99; mi5=880.00; fa5=932.33; sol5=1046.50; la5=1174.66; si5=1318.51; rst=0;

                % 8分0.25 4分0.5 4分点075
                % Melody 1
                m1 = [n(app, 0.5, sol4, instru) n(app, 0.75, mi4, instru) n(app, 0.25, sol4, instru)];
                m2 = [n(app, 1.5, la4, instru)];
                m3 = [n(app, 1, sol4, instru) n(app, 0.25, sol4, instru) n(app, 0.25, la4, instru)];
                m4 = [n(app, 1.5, mi4, instru)];
                m5 = [n(app, 0.75, la3, instru) n(app, 0.25, sol3, instru) n(app, 0.25, la3, instru) n(app, 0.25, do4, instru)];
                m6 = [n(app, 1.5, re4, instru)];
                m7 = [n(app, 0.5, re4, instru) n(app, 0.25, re4, instru) n(app, 0.25, mi4, instru) n(app, 0.25, re4, instru) n(app, 0.25, do4, instru)];
                m8 = [n(app, 1.5, do4, instru)];
                m9 = [n(app, 1, do4, instru) n(app, 0.5, sol4, instru)];
                melody1 = [m1 m2 m3 m4 m5 m6 m7 m8 m9];
                
                % Melody 2
                m11 = [n(app, 0.75, mi4, instru) n(app, 0.25, re4, instru) n(app, 0.25, mi4, instru) n(app, 0.25, sol4, instru)];
                m12 = [n(app, 1, la4, instru) n(app, 0.25, mi4, instru) n(app, 0.25, sol4, instru)];
                m13 = [n(app, 0.75, re4, instru) n(app, 0.25, do4, instru) n(app, 0.25, re4, instru) n(app, 0.25, mi4, instru)];
                m14 = [n(app, 1, sol4, instru) n(app, 0.25, rst, instru) n(app, 0.25, mi4, instru)];
                m15 = [n(app, 0.75, sol4, instru) n(app, 0.25, la4, instru) n(app, 0.25, do5, instru) n(app, 0.25, si4, instru)];
                m16 = [n(app, 1, la4, instru) n(app, 0.25, sol4, instru) n(app, 0.25, mi4, instru)];
                m17 = [n(app, 0.75, re4, instru) n(app, 0.25, do4, instru) n(app, 0.5, la3, instru)];
                m18 = [n(app, 1, re4, instru) n(app, 0.5, sol4, instru)];
                melody2 = [m11 m12 m13 m14 m15 m16 m17 m18];           
                % Melody 3
                m21 = [n(app, 0.75, mi4, instru) n(app, 0.25, re4, instru) n(app, 0.25, mi4, instru) n(app, 0.25, sol4, instru)];
                m22 = [n(app, 1, la4, instru) n(app, 0.25, mi4, instru) n(app, 0.25, sol4, instru)];
                m23 = [n(app, 0.75, re4, instru) n(app, 0.25, do4, instru) n(app, 0.25, re4, instru) n(app, 0.25, mi4, instru)];
                m24 = [n(app, 1, sol4, instru) n(app, 0.25, rst, instru) n(app, 0.25, mi4, instru)];
                m25 = [n(app, 0.75, sol4, instru) n(app, 0.25, la4, instru) n(app, 0.25, do5, instru) n(app, 0.25, si4, instru)];
                m26 = [n(app, 1, la4, instru) n(app, 0.25, sol4, instru) n(app, 0.25, mi4, instru)];
                m27 = [n(app, 0.75, re4, instru) n(app, 0.25, si3, instru) n(app, 0.25, la3, instru) n(app, 0.25, si3, instru)];
                m28 = [n(app, 1, do4, instru) n(app, 0.5, do4, instru)];
                melody3 = [m21 m22 m23 m24 m25 m26 m27 m28];

                % Melody 4
                m31 = [n(app, 0.75, do5, instru) n(app, 0.25, si4, instru) n(app, 0.25, re5, instru) n(app, 0.25, do5, instru)];
                m32 = [n(app, 1, si4, instru) n(app, 0.5, sol4, instru)];
                m33 = [n(app, 1, la4, instru) n(app, 0.5, mi4, instru)];
                m34 = [n(app, 1, sol4, instru) n(app, 0.25, rst, instru) n(app, 0.25, mi4, instru)];
                m35 = [n(app, 0.75, sol4, instru) n(app, 0.25, la4, instru) n(app, 0.25, do5, instru) n(app, 0.25, si4, instru)];
                m36 = [n(app, 1, la4, instru) n(app, 0.25, sol4, instru) n(app, 0.25, mi4, instru)];
                m37 = [n(app, 0.75, re4, instru) n(app, 0.25, do4, instru) n(app, 0.5, la3, instru)];
                m38 = [n(app, 1, re4, instru) n(app, 0.5, sol4, instru)];
                melody4 = [m31 m32 m33 m34 m35 m36 m37 m38];
                % Melody 5
                m41 = [n(app, 0.75, mi4, instru) n(app, 0.25, re4, instru) n(app, 0.25, mi4, instru) n(app, 0.25, sol4, instru)];
                m42 = [n(app, 1, la4, instru) n(app, 0.25, mi4, instru) n(app, 0.25, sol4, instru)];
                m43 = [n(app, 0.75, re4, instru) n(app, 0.25, do4, instru) n(app, 0.25, re4, instru) n(app, 0.25, mi4, instru)];
                m44 = [n(app, 1, sol4, instru) n(app, 0.25, rst, instru) n(app, 0.25, mi4, instru)];
                m45 = [n(app, 0.75, sol4, instru) n(app, 0.25, la4, instru) n(app, 0.25, do5, instru) n(app, 0.25, si4, instru)];
                m46 = [n(app, 1, la4, instru) n(app, 0.25, sol4, instru) n(app, 0.25, mi4, instru)];
                m47 = [n(app, 0.75, re4, instru) n(app, 0.25, si3, instru) n(app, 0.25, la3, instru) n(app, 0.25, si3, instru)];
                m48 = [n(app, 1.5, do4, instru)];
                melody5 = [m41 m42 m43 m44 m45 m46 m47 m48];

                melody = [melody1 melody2 melody3 melody4 melody5];

        end

        function resume_play(app, current_sample)
            app.Label_12.Text = "正在处理……"; drawnow;
            if nargin < 2
                current_sample = 1; % 默认使用当前采样点
            end
            if ~isempty(app.player) 
                app.player = []; music_input = app.music; music_output = music_input;
                    music_output = (app.music_volume / 100) * music_output;
                    music_output = reverb_function(app, music_output, app.music_reverb_choose);
                    music_output = app.music_reverb_volume * music_output + (1 - app.music_reverb_volume) * music_output;
                    music_output = audioEqualizer(app, music_output);
                    music_output = audiostrtcher(app, app.music_speed, music_output);
                app.music_out = music_output;
                show_equalmusic(app);
                
                app.player = audioplayer(app.music_out, app.fs);
                app.player.TimerFcn = @(src, evt)app.update_playback;
                app.player.TimerPeriod = 0.02;
                app.Slider_2.Limits = [0, floor(size(app.music, 1) / (app.music_speed * app.fs))];
                play(app.player, current_sample); %从暂停的采样点开始放
            end
            app.Label_12.Text = "处理完成！";
        end

        function resume_play_old(app, current_sample)
            app.Label_12.Text = "正在处理……";
                    drawnow;
            if nargin < 2
                current_sample = 1; % 默认使用当前采样点
            end
            if ~isempty(app.player)
                app.player = [];
                process_music(app, app.is_enable);
                app.player = audioplayer(app.music_out, app.fs);
                app.player.TimerFcn = @(src, evt)app.update_playback;
                app.player.TimerPeriod = 0.02;
                app.Slider_2.Limits = [0, floor(size(app.music, 1) / (app.music_speed * app.fs))];
                play(app.player, current_sample);                                   %从暂停的采样点开始放
            end
            app.Label_12.Text = "处理完成！";
        end

        function output_music = audiostrtcher(app, speed, input_music)
            parameter = struct();
            parameter.win = hanning(2048);
            parameter.synHop = round(256 * 1/app.music_speed);
            parameter.tolerance = 1024;
            [music_stretched, ~] = wsolaTSM(input_music, 1/speed, parameter, []);
            output_music = music_stretched;

        end
        
        function process_music(app, is_enable)
            if is_enable(1)
                app.music_out = (app.music_volume / 100) * app.music;
            end
            if is_enable(2)
                app.music_out = reverb_function(app, app.music_out, app.music_reverb_choose);
                app.music_out = app.music_reverb_volume .* app.music_out + (app.music_volume / 100) * app.music;
            end
            if is_enable(3)
                app.music_out = audioEqualizer(app, app.music_out);
            end
            if is_enable(4)
                parameter = struct();
                parameter.win = blackman(4096);
                %parameter.synHop = round(256 * 1/app.music_speed); % 根据 stretch_factor 动态调整
                parameter.synHop = 1024;
                parameter.tolerance = 2;
                [to_speed, ~] = wsolaTSM(app.music_out, 1/app.music_speed, parameter, []);
                app.music_out = to_speed;
            end
                show_equalmusic(app);
        end

        function update_playback(app)
            show_playtime(app);
            show_playwave(app);
        end

        function show_playtime(app)
            if isplaying(app.player)
                % 计算当前播放时间（秒）
                current_time = (app.player.CurrentSample - 1) / (app.fs);
                app.Slider_2.Value = current_time;
            end
        end
        
        function show_playwave(app)
                current_sample = app.player.CurrentSample;
                window_seconds = 0.2; % 窗口半宽（实际播放时间）
                window_samples = round(window_seconds * app.music_speed * app.fs);
                start_sample = max(1, current_sample - window_samples);
                end_sample = current_sample + window_samples;
                sample_indices = start_sample:end_sample;
                audio_segment = app.music_out(sample_indices) * (app.music_volume / 100);
                N = length(audio_segment);
                fft_result = fft(audio_segment);
                % 频率轴基于实际播放采样率
                freq_axis = (0:N-1) * (app.fs / N);
                magnitude = abs(fft_result) / N; % 归一化幅度
                % 只显示正频率（单边谱）
                half_N = ceil(N/2);
                freq_axis = freq_axis(1:half_N);
                magnitude = magnitude(1:half_N);
                
                % 绘制带填充的频域波形
                customColor = [1.00,0.41,0.16];
                area(app.UIAxes3, freq_axis, magnitude, ...
                    'FaceColor', customColor, ...  % 填充颜色
                    'EdgeColor', customColor, ...  % 线条颜色
                    'LineWidth', 1.5);             % 适当增加线宽提高可视性
                % 设置频域轴范围（可选）
                %app.UIAxes3.XLim = [0, (app.music_speed * app.fs) / 2]; % 奈奎斯特频率
                app.UIAxes3.XLim = [0, 4000];
                app.UIAxes3.YLim = [0, 0.035];
                
                % 强制刷新界面
                drawnow;
        end

        function final_output_music = reverb_function(app, input_music, number)
            switch number
                case 0
                    final_output_music = input_music;
                case 1
                    final_output_music = reverb_Schroeder(app, input_music);
                case 2
                    final_output_music = reverb_Moorer(app, input_music);
                case 3
                    final_output_music = reverb_Gardner_s(app, input_music);
                case 4
                    final_output_music = (app.music_volume / 100) * app.music;
                    uialert(app.UIFigure, '此功能为VIP专用，请先升级账户！', '提示');
                case 5
                    final_output_music = (app.music_volume / 100) * app.music;
                    uialert(app.UIFigure, '此功能为VIP专用，请先升级账户！', '提示');
            end
        end
        
        function output_music = reverb_Schroeder(app, input_music)
            %% 梳状函数系数处理
            %延时秒数
            delay1 = [29.23, 37.67, 41.49, 44.31];
            %衰减系数
            g1 = [0.94 0.954 0.958 0.96];
            fsa = app.fs;
              % 向量化计算
            %获取各梳状子系统函数分子分母多项式
            num1 = 1;
            comb_out = cell(1, 4);
            parfor k = 1:4
                %各子函数阶数
                D1 = round(delay1(k) * fsa / 1000);
                den = [num1, zeros(1, D1-1), -g1(k)];
                comb_out{k} = filter(1, den, input_music);
            end
            input_music = (comb_out{1}+comb_out{2}+comb_out{3}+comb_out{4}) * 0.25;

            %% 全通函数系数处理
            delay2 = [5, 1.7] / 1000;
            D2 = round(delay2 * app.fs);  % 向量化计算
            g2 = [0.7 0.34];

            num_ap1 = [-g2(1), zeros(1, D2(1)-1), 1];
            den_ap1 = [1, zeros(1, D2(1)-1), -g2(1)];
            input_music = filter(num_ap1, den_ap1, input_music);
            num_ap2 = [-g2(2), zeros(1, D2(2)-1), 1];
            den_ap2 = [1, zeros(1, D2(2)-1), -g2(2)];
            input_music = filter(num_ap2, den_ap2, input_music);

            %% 输出
            output_music = input_music;
        end

        function output_music = reverb_Schroeder_gpu(app, input_music)
            % 将输入数据移至GPU（支持多通道）
            input_music = gpuArray(single(input_music));
            fsa = single(app.fs);
            [nSamples, nChannels] = size(input_music);
            
            %% 梳状滤波器并行处理 (多通道支持)
            delay_ms = single([29.23, 37.67, 41.49, 44.31]);
            gains = single([0.94, 0.954, 0.958, 0.96]);
            
            % 预分配GPU内存 (三维数组: 样本×通道×滤波器)
            comb_output = gpuArray.zeros(nSamples, nChannels, 4, 'single');
            
            % 并行处理所有梳状滤波器
            parfor k = 1:4
                D = round(delay_ms(k) * fsa / 1000);
                den = [1, zeros(1, D-1, 'single'), -gains(k)];
                
                % 多通道滤波
                filtered = input_music;
                for ch = 1:nChannels
                    filtered(:, ch) = filter(1, den, input_music(:, ch));
                end
                comb_output(:, :, k) = filtered;
            end
            
            % 加权平均 (跨滤波器维度)
            input_music = mean(comb_output, 3) * single(0.25);
        
            %% 全通滤波器处理 (多通道支持)
            delay_times = single([5, 1.7]) / 1000;
            ap_gains = single([0.7, 0.34]);
            D_all = round(delay_times * fsa);
            
            for n = 1:2
                D = D_all(n);
                g = ap_gains(n);
                
                % 构造全通滤波器系数
                num = [-g, zeros(1, D-1, 'single'), 1];
                den = [1, zeros(1, D-1, 'single'), -g];
                
                % 多通道滤波
                for ch = 1:nChannels
                    input_music(:, ch) = filter(num, den, input_music(:, ch));
                end
            end
        
            %% 返回结果到CPU
            output_music = gather(input_music);
        end


        function output_music = reverb_Moorer(app, input_music)
            %% 1. 19抽头FIR滤波器 (早期反射)
            % 表1参数：时延(秒)和衰减因子
            delays_fir = [0.0000, 0.0043, 0.0215, 0.0225, 0.0268, 0.0270, 0.0298, ...
                          0.0458, 0.0485, 0.0572, 0.0587, 0.0595, 0.0612, 0.0707, ...
                          0.0708, 0.0726, 0.0741, 0.0753, 0.0797]; % 单位：秒
            gains_fir = [1.000, 0.841, 0.504, 0.491, 0.379, 0.380, 0.346, ...
                         0.289, 0.272, 0.192, 0.193, 0.217, 0.181, 0.180, ...
                         0.181, 0.176, 0.142, 0.167, 0.134];
            
            % 计算最大延迟并创建FIR系数向量
            max_delay = max(round(delays_fir * app.fs));
            b_fir = zeros(1, max_delay + 1);
            for i = 1:length(delays_fir)
                delay_samples = round(delays_fir(i) * app.fs);
                b_fir(delay_samples + 1) = gains_fir(i); % MATLAB索引从1开始
            end
            early_reflections = filter(b_fir, 1, input_music);
            
     %% 2. 6个并联低通梳状滤波器
            % 表2参数：延迟(ms)，增益g，低通系数a
            delay_ms = [50 57 61 69 73 79];
            gs = [0.077 0.090 0.095 0.100 0.105 0.110];
            as = [0.698 0.688 0.684 0.680 0.677 0.673];
            fsa = app.fs;
            % 预分配输出矩阵
            comb_out = cell(1, 6);
            
            % 使用parfor并行处理6个梳状滤波器
            parfor i = 1:6
                D_ms = delay_ms(i);
                g = gs(i);
                a = as(i);
                % 计算延迟阶数
                D_samples = round(D_ms * fsa / 1000);
                % 构造低通梳状滤波器系数
                num = [1, -a]; % 分子多项式
                den = zeros(1, D_samples + 1);
                den(1) = 1;      % z^0项
                den(2) = -a;     % z^{-1}项
                den(end) = -g * (1 - a); % z^{-D}项
                % 应用滤波器
                comb_out{i} = filter(num, den, early_reflections);
            end
            % 合并梳状滤波器输出
            summed_comb = (comb_out{1}+comb_out{2}+comb_out{3}+comb_out{4}+comb_out{5}+comb_out{6}) / 6;
            
            %% 3. 全通滤波器 (A1)
            % 全通滤波器参数 (典型值)
            ap_delay = 6.0 / 1000;  % 6ms延迟
            ap_gain = 0.7;          % 全通增益
            
            % 计算延迟点数
            D_ap = round(ap_delay * fsa);
            
            % 构造全通滤波器系数
            num_ap = [-ap_gain, zeros(1, D_ap - 1), 1];
            den_ap = [1, zeros(1, D_ap - 1), -ap_gain];

            ap_out = filter(num_ap, den_ap, summed_comb);
            %% 延迟一下
            Dd = round(23.7 * fsa /1000);
            num_d = [0, zeros(1, Dd-1), 1];
            d_out = filter(num_d, 1, ap_out);
            
            % 输出
            output_music = (d_out + early_reflections) / 2;
        
        end

        function output_music = reverb_Gardner_s(app, input_music)
            fsa = app.fs;
            % 计算所有延时阶数
            D1 = round(24/1000 * fsa); D2 = round(22/1000 * fsa);
            D3 = round(8.3/1000 * fsa); D4 = round(4.7/1000 * fsa);
            D5 = round(30/1000 * fsa); D6 = round(36/1000 * fsa);    % 36ms

            %% H1z构建
            % H1z = z^(-D1)*((-0.3+Gz)/(1-0.3*Gz))
            % Gz = ((-0.4 + z^(-D2)/(1-0.4*z^(-D2))*((-0.6+z^(-D3))/(1-0.6*z^(-D3))*z^(-D4)
            Gz_num1 = [-0.4, zeros(1, D2-1), 1];
            Gz_den1 = [1, zeros(1, D2-1), -0.4];
            Gz_num2 = [-0.6, zeros(1, D3-1), 1];
            Gz_den2 = [1, zeros(1, D3-1), -0.6];
            Gz_num3 = [0, zeros(1, D4-1), 1];
            %Gz_den3 = 1;
            Gz_num = conv(Gz_num1, Gz_num2);
            Gz_num = conv(Gz_num, Gz_num3);
            Gz_den = conv(Gz_den1, Gz_den2);
            % 嵌套前统一长度
            len = max(length(Gz_num), length(Gz_den));
            Gz_num = [Gz_num, zeros(1, len - length(Gz_num))];
            Gz_den = [Gz_den, zeros(1, len - length(Gz_den))];

            H1z_num1 = [0, zeros(1, D1-1), 1];
            H1z_num2 = -0.3 * Gz_den + Gz_num;
            H1z_den2 = Gz_den - 0.3 * Gz_num;

            H1z_num = conv(H1z_num1, H1z_num2);
            H1z_den = H1z_den2;

            %% H2z构建
            % H2z = ((-0.1 + G2z)/ (1-0.1*G2z))
            % G2z = ((-0.4 + z^(-D5))/(1-0.4*z^(-D5)))*z^(-D6)
            G2z_num1 = [-0.4, zeros(1, D5-1), 1];
            G2z_den1 = [1, zeros(1, D2-1), -0.4];
            G2z_num2 = [zeros(1, D6), 1];
            G2z_num = conv(G2z_num1, G2z_num2);
            G2z_den = G2z_den1;
            % 嵌套前统一长度
            len = max(length(G2z_num), length(G2z_den));
            G2z_num = [G2z_num, zeros(1, len - length(G2z_num))];
            G2z_den = [G2z_den, zeros(1, len - length(G2z_den))];
            H2z_num = -0.1 * G2z_den + G2z_num;
            H2z_den = G2z_den - 0.1 * G2z_num;

            %% H3z构建
            % H3z = 0.3*(b(z)/a(z));
            % [b,a]=butter(1,4200/(app.fs/2))
            [b_lp, a_lp] = butter(1, 4200/(fsa/2)); H3z_gain = 0.3;  % H3z增益
            H3z_num = H3z_gain * b_lp; H3z_den = a_lp;
            
            %% Hz构建
            % Hz = (0.5*H1z*(1+H2z)) / (1-H1z*H2z*H3z);
            len = max(length(H2z_num), length(H2z_den));
            H2z_num = [H2z_num, zeros(1, len - length(H2z_num))];
            H2z_den = [H2z_den, zeros(1, len - length(H2z_den))];
            Hz_num = conv(H1z_num, (H2z_num+H2z_den));
            Hz_num = 0.5 * conv(Hz_num, H3z_den);

            Hz_den1 = conv(H1z_den, H2z_den);
            Hz_den1 = conv(Hz_den1, H3z_den);
            Hz_den2 = conv(H1z_num, H2z_num);
            Hz_den2 = conv(Hz_den2, H3z_num);

            len = max(length(Hz_den1), length(Hz_den2));
            Hz_den1 = [Hz_den1, zeros(1, len - length(Hz_den1))];
            Hz_den2 = [Hz_den2, zeros(1, len - length(Hz_den2))];
            Hz_den = Hz_den1 - Hz_den2;

            output_music = filter(Hz_num, Hz_den, input_music);

        end

        function output_music = audioEqualizer(app, input_music)
            % 参数设置
            Q = sqrt(2); % 品质因数 (1倍频程带宽)
            fsa = app.fs;
            gains = app.eq_gains; % 获取当前增益设置
            
            % 级联所有滤波器
            SOS = []; % 二阶节系数存储
            for k = 1:9
                f0 = app.center_freqs(k);
                G = gains(k);
                A = 10^(G/40); % 注意: 峰值滤波器使用40而非20
                
                % 计算滤波器系数 (RBJ Cookbook 公式)
                w0 = 2*pi*f0/fsa;
                alpha = sin(w0)/(2*Q);
                
                b0 =  1 + alpha*A;
                b1 = -2*cos(w0);
                b2 =  1 - alpha*A;
                a0 =  1 + alpha/A;
                a1 = -2*cos(w0);
                a2 =  1 - alpha/A;
                
                % 归一化系数
                b = [b0, b1, b2]/a0;
                a = [1, a1/a0, a2/a0];
                
                % 添加到二阶节
                SOS = [SOS; [b, a]];
            end
            
            % 级联滤波
            output_music = input_music;
            for k = 1:size(SOS,1)
                output_music = filter(SOS(k,1:3), SOS(k,4:6), output_music);
            end
            
            % 绘制幅频特性曲线
            %plotFrequencyResponse(app, SOS);
        end
        
        function plotFrequencyResponse(app, SOS)
            % 生成对数间隔频率点
            fmin = 20;       % 起始频率 (Hz)
            fmax = app.fs/2; % 奈奎斯特频率
            freqs = logspace(log10(fmin), log10(fmax), 1000);
            
            % 计算总频率响应
            H_total = ones(size(freqs));
            for k = 1:size(SOS,1)
                b = SOS(k,1:3);
                a = SOS(k,4:6);
                [H, ~] = freqz(b, a, freqs, app.fs);
                H_total = H_total .* H;
            end
            
            % 转换dB值
            H_db = 20*log10(abs(H_total));
            
            % 绘制对数坐标曲线
            semilogx(app.UIAxes4, freqs, H_db,'Color', [0.11, 0.38, 0.28],'LineWidth', 1.5);
            grid(app.UIAxes4, 'on');
            xlabel(app.UIAxes4, 'Hz');
            ylabel(app.UIAxes4, 'dB');
            app.UIAxes4.XAxis.Visible = 'on';
            xlim(app.UIAxes4, [fmin, fmax]);
            
            % 标记中心频率
            hold(app.UIAxes4, 'on');
            for f = app.center_freqs
                xline(app.UIAxes4, f, '--', 'Color', [0.11,0.38,0.28]);
            end
            hold(app.UIAxes4, 'off');
        end

        function show_equalmusic(app)
            % 假设输入信号为 app.music_out，采样率为 app.fs
            y = app.music_out; % 输入信号
            ya = app.music;
            fsa = app.fs; % 采样率
            N = length(y); % 信号长度
            
            % 计算 FFT 并取幅值
            Y = abs(fft(y)); % FFT 幅值
            Y = Y(1:floor(N/2)+1); % 取前半部分（正频率）
            Y2 = abs(fft(ya)); % FFT 幅值
            Y2 = Y2(1:floor(N/2)+1); % 取前半部分（正频率）
            % 生成对数间隔频率点
            fmin = 20; % 起始频率 (Hz)
            fmax = fsa/2; % 奈奎斯特频率
            freqs_linear = linspace(0, fsa/2, length(Y)); % 线性频率轴
            freqs = logspace(log10(fmin), log10(fmax), 1000); % 对数间隔频率点
            Y_interp = interp1(freqs_linear, Y, freqs, 'linear', 'extrap'); % 插值到对数频率
            Y_interp2 = interp1(freqs_linear, Y2, freqs, 'linear', 'extrap'); % 插值到对数频率
            
            % 绘制对数坐标曲线
            semilogx(app.UIAxes4, freqs, Y_interp, 'Color', [0.11, 0.38, 0.28], 'LineWidth', 1.5);
            grid(app.UIAxes4, 'on');
            xlabel(app.UIAxes4, 'Hz');
            ylabel(app.UIAxes4, '幅值');
            app.UIAxes4.XAxis.Visible = 'on';
            xlim(app.UIAxes4, [fmin, fmax]);
            ylim(app.UIAxes4, [0 max(Y2)]);

            semilogx(app.UIAxes4_2, freqs, Y_interp2, 'Color', [0.11, 0.38, 0.28], 'LineWidth', 1.5);
            grid(app.UIAxes4_2, 'on');
            xlabel(app.UIAxes4_2, 'Hz');
            ylabel(app.UIAxes4_2, '幅值');
            app.UIAxes4_2.XAxis.Visible = 'on';
            xlim(app.UIAxes4_2, [fmin, fmax]);
            ylim(app.UIAxes4_2, [0 max(Y2)]);
            
            
        end

        function coverPath = extractMP3Cover(app, mp3Path)

            [~, tempName] = fileparts(tempname);
            tempCover = fullfile(tempdir, [tempName, '.jpg']);
            ffmpegpath = ".\env\ffmpeg-7.0.2\bin\ffmpeg.exe";
            cmd = sprintf('"%s" -i "%s" -map 0:v -c copy "%s" -y', ...
                          ffmpegpath, mp3Path, tempCover);
            [status, ~] = system(cmd);
            
            % 检查执行结果
            if status ~= 0 || ~exist(tempCover, 'file')
                if exist(tempCover, 'file')
                    delete(tempCover);
                end
                coverPath = ".\res\basecover.png";
                app.Image3.Visible = "off";
            else
                coverPath = tempCover;
                app.Image3.Visible = "on";
            end
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes3.YAxis.Visible = 'off';
            app.UIAxes4.XAxis.Visible = 'off';
            app.UIFigure.Icon = '.\res\iwmeilogo.png';
            figPosition = app.UIFigure.Position;
            app.UIFigure.Position = [0, 0, figPosition(3), figPosition(4)];
            app.Image3.Visible = "off";
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
           
             % 创建统一的时间轴
             t = linspace(0, 1, app.fs); 
        
             switch value
                case '正弦波'
                    y = app.sound_value*sin(2*pi*app.sound_fs*t);
                case '方波'
                    y = app.sound_value*square(2*pi*app.sound_fs*t);
                case '三角波'
                    y = app.sound_value*sawtooth(2*pi*app.sound_fs*t, 0.5);
                case "白噪声"
                    y = app.sound_value*randn(app.fs, 1);
                otherwise
                    y = zeros(size(t));
             end

             % 创建统一的时间轴
             t = linspace(0, 1, app.fs); 
            
                % 绘图
                plot(app.UIAxes, t, y, 'LineWidth', 1.5);
                xlabel(app.UIAxes, '秒');
                ylabel(app.UIAxes, '幅度');
                ylim(app.UIAxes, [-max(y)-0.1 max(y)+0.1]);
                grid(app.UIAxes, 'on');
            app.signal = y;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            y = app.signal;
            % 播放声音
            sound(y, app.fs);  % 播放音频
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
                switch value
                    case '请选择'
                        y=0; fsa = 0;
                    case '你不要那样看着我的眼睛'
                        filename = '你不要那样看着我的眼睛.mp3';
                        [y, fsa] = audioread(filename);
                    case '庭院深深'
                        filename = '庭院深深.mp3';
                        [y, fsa] = audioread(filename);
                    case '别说是你'
                        filename = '别说是你.mp3';
                        [y, fsa] = audioread(filename);
                    case 'TYSS_Digit'
                        y = app.sound_make(app.sound_inst);
                        fsa = 44100;
                end
                app.audio = {y, fsa};
                app.audio_changed = {y, fsa};
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            clear sound;    
            to_play = app.audio_changed;
            sound(to_play{1}, to_play{2});
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.sound_fs = str2double(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.sound_value = str2double(value);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            clear sound % 停止播放
            cla(app.UIAxes); % 清除波形图像
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
          value = app.Slider.Value;
          app.music_volume = value;
          app.is_enable = [1 0 0 0];
          current_sample = app.player.CurrentSample;
          resume_play(app, current_sample);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            [filename, pathname] = uigetfile({'*.mp3;*.wav;*.flac;*.ogg','音频文件 (*.mp3, *.wav, *.flac, *.ogg)'; '*.*', '所有文件'}, '选择一个音频文件');
            if isequal(filename, 0)
                app.Label_7.Text = "当前：";
                app.Image2.ImageSource = "basecover.png";
                app.Image3.Visible = "off";
                return;
            end
            app.Label_7.Text = "当前：" + filename;
            fullpath = fullfile(pathname, filename);
            [y, fsp] = audioread(fullpath);
            app.Image2.ImageSource = extractMP3Cover(app, fullpath);
            app.fs = fsp;
            app.music = y;
            app.music_out = y;
            app.music_name = fullpath;
            app.player = audioplayer(app.music_volume / 100 * y, app.fs);
            app.music_speed = 1;
            app.Slider_2.Limits = [0, floor(size(app.music, 1) / (app.music_speed * app.fs))];
            app.Slider_2.Value = 0;
            app.player.TimerFcn = @(src, evt)app.update_playback;
            app.player.TimerPeriod = 0.02;
            app.Slider_3.Value = 1;
            app.Label_12.Text = "";
            app.Label_13.Text = num2str(fsp) + "Hz";
            app.DropDown_4.Value = "请选择";
            app.Slider_2.Value = 0;
            app.Slider.Value = 100;
            app.DropDown_3.Value = '1';
            app.kHzSlider_5.Value = 0;
            app.kHzSlider_4.Value = 0;
            app.kHzSlider_3.Value = 0;
            app.kHzSlider_2.Value = 0;
            app.kHzSlider.Value = 0;
            app.HzSlider_4.Value = 0;
            app.HzSlider_3.Value = 0;
            app.HzSlider_2.Value = 0;
            app.HzSlider.Value = 0;
            app.music_reverb_choose = 0;
            app.eq_gains = [0 0 0 0 0 0 0 0 0];
            app.music_state = struct( ...
            'speed', app.music_speed, ...
            'reverb_type', app.music_reverb_choose, ...
            'reverb_volume', app.music_reverb_volume, ...
            'volume', app.music_volume, ...
            'equalizer', app.eq_gains ...
            );
            semilogx(app.UIAxes4, 0, 0);
            semilogx(app.UIAxes4_2, 0, 0);
            drawnow;
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            
            value = app.DropDown_3.Value;
            if value > '2'
                value = '1';
                app.DropDown_3.Value = '1';
                uialert(app.UIFigure, '禁止恶搞！', '警告');
            end
            app.is_enable = [0 0 0 1];
            app.music_speed = str2double(value);
            current_sample = app.player.CurrentSample;
            resume_play(app, current_sample);
           
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
        %% 按下播放按钮的操作
            if isempty(app.music)
                uialert(app.UIFigure, '请先选择一首音乐文件！', '提示');
                return;
            end
            % 如果播放器有歌，则判断是否正在播放
            if ~isempty(app.player)
                if isplaying(app.player)
                    return;
                else
                    resume(app.player);
                    return;
                end
            end

            %如果没歌，则开始放
            play(app.player);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
           if ~isempty(app.player)
                pause(app.player);                                          % 暂停当前播放
                drawnow;
                next_time = app.Slider_2.Value;                             % 获取滑块的新值（秒）
                next_sample = max(1, min(round(next_time * app.fs), size(app.music, 1)));
                resume_play(app, next_sample);
            end
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            if ~isempty(app.player) && isplaying(app.player)
                pause(app.player);
            end
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            switch value
                case '声学吉他'
                    app.sound_inst = 3;
                    clear sound;
                    y = app.sound_make(app.sound_inst);
                    app.audio_changed = {y, 44100};
                case '长笛'
                    app.sound_inst = 2;
                    clear sound;
                    y = app.sound_make(app.sound_inst);
                    app.audio_changed = {y, 44100};
                case '指数衰减'
                    app.sound_inst = 1;
                    clear sound;
                    y = app.sound_make(app.sound_inst);
                    app.audio_changed = {y, 44100};
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            model = app.DropDown_4.Value;
            app.is_enable = [0 1 0 0];
            switch model
                case '请选择'
                    return;
                case '无混响'
                    app.music_reverb_choose = 0;
                    current_sample = app.player.CurrentSample;
                    resume_play(app, current_sample);
                case 'Schroeder 混响模型'
                    app.music_reverb_choose = 1;
                    current_sample = app.player.CurrentSample;
                    resume_play(app, current_sample);
                case 'Moorer 混响模型'
                    app.music_reverb_choose = 2;
                    current_sample = app.player.CurrentSample;
                    resume_play(app, current_sample);
                case 'Gardner 小厅混响模型'
                    app.music_reverb_choose = 3;
                    current_sample = app.player.CurrentSample;
                    resume_play(app, current_sample);
                case 'Gardner 中厅混响模型'
                    app.music_reverb_choose = 4;
                    current_sample = app.player.CurrentSample;
                    resume_play(app, current_sample);
                case 'Gardner 大厅混响模型'
                    app.music_reverb_choose = 5;
                    current_sample = app.player.CurrentSample;
                    resume_play(app, current_sample);
            end
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            app.music_reverb_volume = value;
            app.is_enable = [0 1 0 0];
            current_sample = app.player.CurrentSample;
            resume_play(app, current_sample);
        end

        % Value changed function: HzSlider
        function HzSliderValueChanged(app, event)
            value = app.HzSlider.Value;
            app.eq_gains(1) = value;
            
        end

        % Value changed function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
            value = app.HzSlider_2.Value;
            app.eq_gains(2) = value;
            
        end

        % Value changed function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
            value = app.HzSlider_3.Value;
            app.eq_gains(3) = value;
            
        end

        % Value changed function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
            value = app.HzSlider_4.Value;
            app.eq_gains(4) = value;
            
        end

        % Value changed function: kHzSlider
        function kHzSliderValueChanged(app, event)
            value = app.kHzSlider.Value;
            app.eq_gains(5) = value;
            
        end

        % Value changed function: kHzSlider_2
        function kHzSlider_2ValueChanged(app, event)
            value = app.kHzSlider_2.Value;
            app.eq_gains(6) = value;
            
        end

        % Value changed function: kHzSlider_3
        function kHzSlider_3ValueChanged(app, event)
            value = app.kHzSlider_3.Value;
            app.eq_gains(7) = value;
            
        end

        % Value changed function: kHzSlider_4
        function kHzSlider_4ValueChanged(app, event)
            value = app.kHzSlider_4.Value;
            app.eq_gains(8) = value;
            
        end

        % Value changed function: kHzSlider_5
        function kHzSlider_5ValueChanged(app, event)
            value = app.kHzSlider_5.Value;
            app.eq_gains(9) = value;
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)

            current_sample = app.player.CurrentSample;
            app.is_enable = [0 0 1 0];
            app.eq_gains = [0 0 0 0 0 0 0 0 0];
            resume_play(app, current_sample);
            app.kHzSlider_5.Value = 0;
            app.kHzSlider_4.Value = 0;
            app.kHzSlider_3.Value = 0;
            app.kHzSlider_2.Value = 0;
            app.kHzSlider.Value = 0;
            app.HzSlider_4.Value = 0;
            app.HzSlider_3.Value = 0;
            app.HzSlider_2.Value = 0;
            app.HzSlider.Value = 0;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            current_sample = app.player.CurrentSample;
            app.is_enable = [0 0 1 0];
            resume_play(app, current_sample);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '示例功能区';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '波形生成';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'请选择', '正弦波', '方波', '三角波', '白噪声'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '请选择';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '示例音乐';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'请选择', '你不要那样看着我的眼睛', '庭院深深', '别说是你', 'TYSS_Digit'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '请选择';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '播放波形';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '播放音乐';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '重置音乐';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '放送频率';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '放送幅度';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '音色套用';
            app.DropDown_5 = uidropdown(app.Panel);
            app.DropDown_5.Items = {'请选择', '声学吉他', '长笛', '指数衰减'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '请选择';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '示例波形显示';
            app.UIAxes = uiaxes(app.Panel_2);
            xlabel(app.UIAxes, '秒')
            ylabel(app.UIAxes, '幅度')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel_7.Title = '九频段均衡器';
            app.HzSliderLabel = uilabel(app.Panel_7);
            app.HzSliderLabel.Text = '64Hz';
            app.HzSlider = uislider(app.Panel_7);
            app.HzSlider.Limits = [-12 12];
            app.HzSlider.ValueChangedFcn = createCallbackFcn(app, @HzSliderValueChanged, true);
            app.HzSlider_2Label = uilabel(app.Panel_7);
            app.HzSlider_2Label.Text = '125Hz';
            app.HzSlider_2 = uislider(app.Panel_7);
            app.HzSlider_2.Limits = [-12 12];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzSlider_3Label = uilabel(app.Panel_7);
            app.HzSlider_3Label.Text = '250Hz';
            app.HzSlider_3 = uislider(app.Panel_7);
            app.HzSlider_3.Limits = [-12 12];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.HzSlider_4Label = uilabel(app.Panel_7);
            app.HzSlider_4Label.Text = '500Hz';
            app.HzSlider_4 = uislider(app.Panel_7);
            app.HzSlider_4.Limits = [-12 12];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.kHzSliderLabel = uilabel(app.Panel_7);
            app.kHzSliderLabel.Text = '1kHz';
            app.kHzSlider = uislider(app.Panel_7);
            app.kHzSlider.Limits = [-12 12];
            app.kHzSlider.ValueChangedFcn = createCallbackFcn(app, @kHzSliderValueChanged, true);
            app.kHzSlider_2Label = uilabel(app.Panel_7);
            app.kHzSlider_2Label.Text = '2kHz';
            app.kHzSlider_2 = uislider(app.Panel_7);
            app.kHzSlider_2.Limits = [-12 12];
            app.kHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_2ValueChanged, true);
            app.kHzSlider_3Label = uilabel(app.Panel_7);
            app.kHzSlider_3Label.Text = '4kHz';
            app.kHzSlider_3 = uislider(app.Panel_7);
            app.kHzSlider_3.Limits = [-12 12];
            app.kHzSlider_3.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_3ValueChanged, true);
            app.kHzSlider_4Label = uilabel(app.Panel_7);
            app.kHzSlider_4Label.Text = '8kHz';
            app.kHzSlider_4 = uislider(app.Panel_7);
            app.kHzSlider_4.Limits = [-12 12];
            app.kHzSlider_4.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_4ValueChanged, true);
            app.kHzSlider_5Label = uilabel(app.Panel_7);
            app.kHzSlider_5Label.Text = '16kHz';
            app.kHzSlider_5 = uislider(app.Panel_7);
            app.kHzSlider_5.Limits = [-12 12];
            app.kHzSlider_5.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_5ValueChanged, true);
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = '放送功能区';
            app.Label_5 = uilabel(app.Panel_5);
            app.Label_5.Text = '音量';
            app.Slider = uislider(app.Panel_5);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 100;
            app.Button_7 = uibutton(app.Panel_5, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '选择目标放送曲目';
            app.Label_6 = uilabel(app.Panel_5);
            app.Label_6.Text = '倍速值';
            app.DropDown_3 = uidropdown(app.Panel_5);
            app.DropDown_3.Items = {'0.25', '0.5', '1', '1.5', '2', '8'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '1';
            app.Label_7 = uilabel(app.Panel_5);
            app.Label_7.Text = '当前：';
            app.Image = uiimage(app.Panel_5);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'res', 'play_icon.png');
            app.Image_2 = uiimage(app.Panel_5);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'res', 'pause_icon.png');
            app.Slider_2 = uislider(app.Panel_5);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_8 = uilabel(app.Panel_5);
            app.Label_8.Text = '混响模型';
            app.DropDown_4 = uidropdown(app.Panel_5);
            app.DropDown_4.Items = {'请选择', '无混响', 'Schroeder 混响模型', 'Moorer 混响模型', 'Gardner 小厅混响模型', 'Gardner 中厅混响模型', 'Gardner 大厅混响模型'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '请选择';
            app.Label_11 = uilabel(app.Panel_5);
            app.Label_11.Text = '混响效果';
            app.Slider_3 = uislider(app.Panel_5);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Label_12 = uilabel(app.Panel_5);
            app.Label_12.Text = '';
            app.Label_13 = uilabel(app.Panel_5);
            app.Label_13.Text = '';
            app.Button_8 = uibutton(app.Panel_5, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '重置均衡';
            app.Button_9 = uibutton(app.Panel_5, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '套用均衡';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Title = '放送频域';
            app.UIAxes3 = uiaxes(app.Panel_6);
            app.Image3 = uiimage(app.Panel_6);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'basecover.png');
            app.Image2 = uiimage(app.Panel_6);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'basecover.png');
            app.Panel_8 = uipanel(app.UIFigure);
            app.Panel_8.Title = '均衡器效果显示';
            app.UIAxes4 = uiaxes(app.Panel_8);
            app.UIAxes4_2 = uiaxes(app.Panel_8);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = day3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end